classdef BattleShip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        EnemyShips             matlab.ui.control.Image
        UserShips              matlab.ui.control.Image
        Knob_2                 matlab.ui.control.DiscreteKnob
        Knob_3                 matlab.ui.control.DiscreteKnob
        coordLabel             matlab.ui.control.Label
        ArmMissileSwitchLabel  matlab.ui.control.Label
        ArmMissileSwitch       matlab.ui.control.ToggleSwitch
        Lamp                   matlab.ui.control.Lamp
        Image3                 matlab.ui.control.Image
        EnemyShipsLabel        matlab.ui.control.Label
        YourShipsLabel         matlab.ui.control.Label
        CoordinatesLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        x_coord = 'A';
        y_coord = '1';
        grid_img_location = '/grid.jpg';
    end


    methods (Access = private)
        
        function update_coord(app)
            app.coordLabel.Text = [app.x_coord app.y_coord];
            disp(app.EnemyShips());
            disp(get(app.UserShips));
            app.open_grid();
        end
        
        function open_grid(app)
            figure;
            hold on;
            width = 500;
            height = 500;
            set(gcf, 'Position',  [100, 100, 800, 800])
            subplot(2,1,1);
            enemyGrid = imread('/Users/Vincent/Documents/GitProjects/MatlabAssignments/ProjectConcept/img/Grid.jpg');
            imagesc(enemyGrid);
            subplot(2,1,2);
            userGrid = imread('/Users/Vincent/Documents/GitProjects/MatlabAssignments/ProjectConcept/img/Grid.jpg');
            imagesc(userGrid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob_3
        function Knob_3ValueChanged(app, event)
            value = app.Knob_3.Value;
            app.x_coord = char(value);
            app.update_coord();
        end

        % Value changed function: Knob_2
        function Knob_2ValueChanged(app, event)
            value = app.Knob_2.Value;
            app.y_coord = char(value);
            app.update_coord();
        end

        % Value changed function: ArmMissileSwitch
        function ArmMissileSwitchValueChanged(app, event)
            value = app.ArmMissileSwitch.Value;
            if strcmp(value, 'Armed')
                app.Lamp.Color = [0.9,0,0];
            else
                app.Lamp.Color = [0.65,0.65,0.65];
            end
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            rectangle(app.UIAxes, 'Position', [10,15,15,10]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EnemyShips = uiimage(app.GridLayout2);
            app.EnemyShips.ImageSource = 'grid.jpg';
            app.UserShips = uiimage(app.GridLayout2);
            app.UserShips.ImageSource = 'grid.jpg';
            app.Knob_2 = uiknob(app.GridLayout, 'discrete');
            app.Knob_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Knob_2ValueChanged, true);
            app.Knob_2.Value = '1';
            app.Knob_3 = uiknob(app.GridLayout, 'discrete');
            app.Knob_3.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J'};
            app.Knob_3.ValueChangedFcn = createCallbackFcn(app, @Knob_3ValueChanged, true);
            app.Knob_3.Value = 'A';
            app.coordLabel = uilabel(app.GridLayout);
            app.coordLabel.Text = 'A1';
            app.ArmMissileSwitchLabel = uilabel(app.GridLayout);
            app.ArmMissileSwitchLabel.Text = 'Arm Missile';
            app.ArmMissileSwitch = uiswitch(app.GridLayout, 'toggle');
            app.ArmMissileSwitch.Items = {'Off', 'Armed'};
            app.ArmMissileSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmMissileSwitchValueChanged, true);
            app.Lamp = uilamp(app.GridLayout);
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = 'LaunchButton.jpg';
            app.EnemyShipsLabel = uilabel(app.GridLayout);
            app.EnemyShipsLabel.Text = 'Enemy Ships';
            app.YourShipsLabel = uilabel(app.GridLayout);
            app.YourShipsLabel.Text = 'Your Ships';
            app.CoordinatesLabel = uilabel(app.GridLayout);
            app.CoordinatesLabel.Text = 'Coordinates';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BattleShip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end